classdef EDS_Grain_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        GrainAnalyzerTab     matlab.ui.container.Tab
        LoadDataButton       matlab.ui.control.Button
        WriteDataButton      matlab.ui.control.Button
        GrainLabel           matlab.ui.control.Label
        GrainEditField       matlab.ui.control.NumericEditField
        SelectGrainsButton   matlab.ui.control.Button
        SheetNameLabel       matlab.ui.control.Label
        RegionEditField      matlab.ui.control.EditField
        RegionLabel          matlab.ui.control.Label
        SheetNameEditField   matlab.ui.control.EditField
        ReadOut              matlab.ui.control.Label
        GrainAxes            matlab.ui.control.UIAxes
        DopingMapTab         matlab.ui.container.Tab
        CEditField_2Label_2  matlab.ui.control.Label
        SelectPixelButton    matlab.ui.control.Button
        SiReadout            matlab.ui.control.NumericEditField
        SiLabel              matlab.ui.control.Label
        C2Label              matlab.ui.control.Label
        NEditField           matlab.ui.control.NumericEditField
        NEditFieldLabel      matlab.ui.control.Label
        C1EditField          matlab.ui.control.NumericEditField
        C2EditField          matlab.ui.control.NumericEditField
        SaveMapButton        matlab.ui.control.Button
        MapAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        g = 0;                                      % counter for number of grains selected (resets after sheet name change)
        
        path
        excel_filename = 'EDS Grain Analysis.xlsx'; % name of file being written

        scale_factor = 0.25;

        x_size
        y_size
        data                                        % three dimensional array of the original B, C and Si intensities
        cmap

        x                                           % N x 2 dimensional array containing that start and end x position of the user grain selection
        y                                           % N x 2 dimensional array containing that start and end y position of the user grain selection

        carbon
        silicon

%         m = [0.75, -0.45];                          % Scaling factors for C/B and Si/B
%         b = [0.83, 0.63];                           % Scaling factors for C/B and Si/B

        m = [0.496, -1.206];                          % Scaling factors for C/B and Si/B
        b = [0.396, 0.371];                           % Scaling factors for C/B and Si/B
        atomic_mass = [10.811, 12.011, 28.0855];    % Atomic mass of B, C and Si
    end
    
    methods (Access = private)
        function counts = average_pixels(app, type)
            counts = NaN(app.g - 1,3);
            
            if strcmp(type, 'Data')
                nans = NaN(app.y_size, app.x_size, 3);

                data = app.data;
                data(repmat(app.silicon > app.C2EditField.Value, [1 1 3])) = nans(repmat(app.silicon > app.C2EditField.Value, [1 1 3]));

                % Average the intensities contained within the user specified region and store it in an 
                % N x 3 array where the columns correspond to B, C and Si respectively
                for i = 1:app.g - 1
                    counts(i,1) = sum(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 1), 'all', 'omitnan');
                    counts(i,2) = sum(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 2), 'all', 'omitnan');
                    counts(i,3) = sum(data(app.y(i,1):app.y(i,2), app.x(i,1):app.x(i,2), 3), 'all', 'omitnan');
                end

            elseif strcmp(type, 'Image')
                % Find the average of an nxn box of pixels surrounding a central pixel and replace it with the average 
                n = app.NEditField.Value;

                if mod(n,2) == 0
                    n = n + 1;
                end
    
                kernel = ones(n);
    
                for i = 1:3
                    data(:,:,i) = conv2(app.data(:,:,i), kernel, 'same');
                end
    
                ij = app.x_size * app.y_size;
                
                %% Compute average intensity for each grain
                counts = NaN(ij,3);
                
                for index = 1:ij
                    i = mod(index, app.x_size);
                    if i == 0
                        i = app.x_size;
                    end
                    j = ceil(index / app.x_size);
                
                    counts(index,1) = data(j,i,1);
                    counts(index,2) = data(j,i,2);
                    counts(index,3) = data(j,i,3);
                end
            end
        end

            
        function at_percent = zeta_factor(app, counts)
            n = size(counts,1);
            
            %% Compute average intensity for each grain
            [intensity_ratio, scaling_factor, rel_intensity, wt_fraction, mol, at_percent] = deal(ones(n,3));
    
            %% Apply the Scaling Factors
            for i = 2:3
                % Compute elemental intensity ratios to be used in scaling factor calcs
                intensity_ratio(:,i) = counts(:,i) ./ counts(:,1);
                % Calculate scaling factors for each point along line averaged intensity
                scaling_factor(:,i) = app.b(i-1) * exp(app.m(i-1) .* intensity_ratio(:,i));
                % Apply scaling factors to get corrected relative intensity of each element
                rel_intensity(:,i) = scaling_factor(:,i) .* intensity_ratio(:,i);
            end
            
            % Compute the total intensity
            total_intensity = rel_intensity(:,1) + rel_intensity(:,2) + rel_intensity(:,3);
        
            for i = 1:3
                % Convert corrected intensities to weight fractions
                wt_fraction(:,i) = rel_intensity(:,i) ./ total_intensity;
                % Convert weight fractions to relative mol fraction
                mol(:,i) = wt_fraction(:,i) / app.atomic_mass(i);
            end
        
            % Compute the total number of moles
            total_mols = mol(:,1) + mol(:,2) + mol(:,3);
        
            for i = 1:3
                % Convert relative mol fraction to atomic percents
                at_percent(:,i) = 100 * mol(:,i) ./ total_mols;
            end
        end

        
        function [boron, carbon, silicon] = vector_to_array(app, at_percent)     
            [boron, carbon, silicon] = deal(NaN(app.y_size,app.x_size));
            
            ij = app.x_size * app.y_size;

            for index = 1:ij
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                boron(j,i) = at_percent(index,1);
                carbon(j,i) = at_percent(index,2);
                silicon(j,i) = at_percent(index,3);
            end
        end


        function full_excel_filename = write_to_table(app, region, at_percent)
            %%
            % Write the data to the sheetname specified in the sheetname edit field
            sheetname = app.SheetNameEditField.Value;

            % If the sheetname is empty, data is stored in 'Default'
            if isempty(sheetname)
                sheetname = 'Default';
            % Check if sheetname exceeds Excel's 31 character limit and truncate it
            elseif size(sheetname,2)>31
                sheetname = sheetname(1:31);
            end

            %% Prepare data for export
            % Compute the B to C ratio
            b_to_c_ratio = at_percent(:,1) ./ at_percent(:,2);

            % Combine the X, Y, Atomic Percent and B to C Ratio columns
            column_data = array2table(num2cell([app.x, app.y, at_percent, b_to_c_ratio]));
        
            % Label the data using the user input in the  region edit field
            region_label = {region, blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};

            % Create data headers to label the columns
            column_labels = {'X Start', 'X End', 'Y Start', 'Y End', 'B (at.%)', 'C (at.%)', 'Si (at.%)', 'B/C Ratio'}; 

            % Creat a row of blanks to improve readability of written data
            separator = {blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1), blanks(1)};
        
            % Combine data and headers into one big table to write to Excel
            composite_data = [region_label; column_labels; column_data; separator];

            % Define filename to write the data to
            full_excel_filename = fullfile(app.path, app.excel_filename);
        
            %% Write data to output Excel spreadsheet
            writetable(composite_data, full_excel_filename, 'Sheet', sheetname, 'WriteVariableNames', false, 'WriteMode', 'append');

            app.ReadOut.Text = append('File Location: ', full_excel_filename);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Turn off the figure window so the user can see the uigetfile window
            app.UIFigure.Visible = 'off';
            app.GrainAxes.Visible = 'off';
            
            % Clear axes
            cla(app.GrainAxes)
            cla(app.MapAxes)

            % Restore default properties
            app.ReadOut.Text = '';
        
            %% Prompt user to select 3 data files
            [filename, app.path] = uigetfile('*.csv', 'Select EDS data files', 'MultiSelect', 'on');
            app.UIFigure.Visible = 'on';

            % Determine number of files selected
            number_of_files = length(filename);

            % Turn the figure window back on
            app.UIFigure.Visible = 'on';
    
            %% Read Data
            [app.y_size, app.x_size] = size(readmatrix(strcat(app.path,filename{1})));
            app.data = zeros(app.y_size,app.x_size,number_of_files);
        
            for i = 1:number_of_files
                % Create path to file
                mapFile = strcat(app.path,filename{i});
        
                % Read the data file
                app.data(:,:,i) = readmatrix(mapFile);
            end

            app.data = imresize(app.data, app.scale_factor, 'bicubic');

            app.y_size = size(app.data,1);
            app.x_size = size(app.data,2);

            counts = average_pixels(app, 'Image');
            at_percent = zeta_factor(app, counts);
            [~, app.carbon, app.silicon] = vector_to_array(app, at_percent);

            app.cmap = colormap(app.GrainAxes,'jet'); 
            app.cmap(1,:) = [0,0,0]; 
            app.cmap(end,:) = [1,1,1];

            %% Display BW EDS Map
            one = ones(app.y_size, app.x_size);
            zero = zeros(app.y_size, app.x_size);
            zero(app.silicon > app.C2EditField.Value) = one(app.silicon > app.C2EditField.Value);

            imagesc(app.GrainAxes,zero);
            colormap(app.GrainAxes,'gray');
            colorbar(app.GrainAxes,'Parent',app.GrainAnalyzerTab)
            clim(app.GrainAxes,[0 1])
            hold(app.GrainAxes,'on')

            % Scale axes to match the map size
            app.GrainAxes.XLim = [1 app.x_size];
            app.GrainAxes.YLim = [1 app.y_size];

            %% Display Color EDS Map
            imagesc(app.MapAxes,app.silicon);

            app.MapAxes.Color = 'none';
            colorbar(app.MapAxes,'Parent',app.DopingMapTab)
            colormap(app.MapAxes,app.cmap);
            clim(app.MapAxes,[0,3])

            app.C2EditField.Value = 3;

            % Scale axes to match the map size
            app.MapAxes.XLim = [1 app.x_size];
            app.MapAxes.YLim = [1 app.y_size];

            %% Compute the Average over the Entire Scan
            app.g = 2;
            app.x = [1 app.x_size];
            app.y = [1 app.y_size];

            counts = average_pixels(app, 'Data');
            at_percent = zeta_factor(app, counts);

            write_to_table(app, 'Full Scan Area', at_percent);

            % Initialize selection counter and position arrays
            app.g = 1;
            app.x = [];
            app.y = [];

            % Turn on axes
            app.GrainAxes.Visible = 'on';
        end

        % Button pushed function: WriteDataButton
        function WriteDataButtonPushed(app, event)
            counts = average_pixels(app, 'Data');
            at_percent = zeta_factor(app, counts);
            
            write_to_table(app, app.RegionEditField.Value, at_percent);

            cla(app.GrainAxes)

            %% Display BW EDS Map
            one = ones(app.y_size, app.x_size);
            zero = zeros(app.y_size, app.x_size);
            zero(app.silicon > app.C2EditField.Value) = one(app.silicon > app.C2EditField.Value);

            imagesc(app.GrainAxes,zero);
            colormap(app.GrainAxes,'gray');
            colorbar(app.GrainAxes,'Parent',app.GrainAnalyzerTab)
            clim(app.GrainAxes,[0 1])
            hold(app.GrainAxes,'on')

            app.g = 1;
            app.GrainLabel.Text = append('Grain: ', int2str(app.g - 1));

            [app.x, app.y] = deal([]);

            app.ReadOut.Text = 'Change sheet name before loading new data.';
        end

        % Button pushed function: SelectGrainsButton
        function SelectGrainsButtonPushed(app, event)
            n = app.GrainEditField.Value;
            
            for i = 1:n
                % Record location of first point
                [x_coord(1,1), y_coord(1,1)] = ginput(1);
    
                % Plot the first point for user reference
                pt1 = scatter(x_coord(1,1), y_coord(1,1), 'r', 'filled', 'Parent', app.GrainAxes);
    
                % Record location of second point
                [x_coord(1,2), y_coord(1,2)] = ginput(1);
    
                % Plot the second point for user reference
                pt2 = scatter(x_coord(1,2), y_coord(1,2), 'r', 'filled', 'Parent', app.GrainAxes);
                
                % Determine corner of rectangle
                x_min = min(x_coord); x_max = max(x_coord);
                y_min = min(y_coord); y_max = max(y_coord);
    
                % Compute rectangle dimensions
                w = x_max - x_min;
                h = y_max - y_min;
    
                % Discard user selection if the either point lies outside of the map bounds
                if (x_min < 1) || (y_min < 1) || (x_max > app.x_size) || (y_max > app.y_size)
                    app.ReadOut.Text = 'Grain selection area outside of axis bounds!';
                    delete(pt1)
                    delete(pt2)
                else
                    % Plot rectangle on top of image
                    rectangle('Position', [x_min y_min w h], 'EdgeColor', 'r', 'LineWidth', 2, 'Parent', app.GrainAxes)
            
                    % Store values
                    % Start is top left and end is bottom right since the image origin is top left
                    app.x(app.g,1) = ceil(x_min); app.x(app.g,2) = floor(x_max);
                    app.y(app.g,1) = ceil(y_min); app.y(app.g,2) = floor(y_max);
                    
                    % Increment the counters
                    app.g = app.g + 1;
                    app.GrainLabel.Text = append('Grain: ', int2str(app.g - 1));
                end
            end
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            counts = average_pixels(app, 'Image');
            at_percent = zeta_factor(app, counts);
            [~, app.carbon, app.silicon] = vector_to_array(app, at_percent);
            
            cla(app.GrainAxes)
            cla(app.MapAxes)
            
            %% Display BW EDS Map
            one = ones(app.y_size, app.x_size);
            zero = zeros(app.y_size, app.x_size);
            zero(app.silicon > app.C2EditField.Value) = one(app.silicon > app.C2EditField.Value);

            imagesc(app.GrainAxes,zero);
            colormap(app.GrainAxes,'gray');
            colorbar(app.GrainAxes,'Parent',app.GrainAnalyzerTab)
            clim(app.GrainAxes,[0 1])
            hold(app.GrainAxes,'on')

            %% Display Color EDS Map
            imagesc(app.MapAxes,app.silicon);

            app.MapAxes.Color = 'none';
            colorbar(app.MapAxes,'Parent',app.DopingMapTab)
            colormap(app.MapAxes,app.cmap);
            clim(app.MapAxes,[0,3])

            app.C2EditField.Value = 3;
        end

        % Button pushed function: SelectPixelButton
        function SelectPixelButtonPushed(app, event)
            pixel = ginput(1);

            x_coord = round(pixel(1));
            y_coord = round(pixel(2));

            si = app.silicon(y_coord,x_coord);
            
            app.SiReadout.Value = round(si,1);
        end

        % Value changed function: C1EditField
        function C1EditFieldValueChanged(app, event)
            colorbar_limits = app.MapAxes.CLim;
            colorbar_limits(1) = app.C1EditField.Value;
            clim(app.MapAxes, colorbar_limits)
        end

        % Value changed function: C2EditField
        function C2EditFieldValueChanged(app, event)
            cla(app.GrainAxes)
            
            %% Display BW EDS Map
            one = ones(app.y_size, app.x_size);
            zero = zeros(app.y_size, app.x_size);
            zero(app.silicon > app.C2EditField.Value) = one(app.silicon > app.C2EditField.Value);

            imagesc(app.GrainAxes,zero);
            colormap(app.GrainAxes,'gray');
            colorbar(app.GrainAxes,'Parent',app.GrainAnalyzerTab)
            clim(app.GrainAxes,[0 1])
            
            colorbar_limits = app.MapAxes.CLim;
            colorbar_limits(2) = app.C2EditField.Value;
            clim(app.MapAxes, colorbar_limits)
        end

        % Button pushed function: SaveMapButton
        function SaveMapButtonPushed(app, event)
            % Generate the new figure name
            figure_name = append(app.path, 'Si EDS Map.png');

            % Save the figure
            exportgraphics(app.MapAxes, figure_name, 'Resolution', 300)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrainAnalyzerTab = uitab(app.TabGroup);
            app.GrainAnalyzerTab.Title = 'Grain Analyzer';
            app.GrainAxes = uiaxes(app.GrainAnalyzerTab);
            app.GrainAxes.XTick = [];
            app.GrainAxes.YTick = [];
            app.GrainAxes.ZTick = [];
            app.GrainAxes.ZTickLabel = '';
            app.GrainAxes.Visible = 'off';
            app.ReadOut = uilabel(app.GrainAnalyzerTab);
            app.ReadOut.Text = 'Change sheet name before loading data.';
            app.SheetNameEditField = uieditfield(app.GrainAnalyzerTab, 'text');
            app.RegionLabel = uilabel(app.GrainAnalyzerTab);
            app.RegionLabel.Text = 'Region:';
            app.RegionEditField = uieditfield(app.GrainAnalyzerTab, 'text');
            app.SheetNameLabel = uilabel(app.GrainAnalyzerTab);
            app.SheetNameLabel.Text = 'Sheet Name:';
            app.SelectGrainsButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.SelectGrainsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGrainsButtonPushed, true);
            app.SelectGrainsButton.Text = 'Select Grains';
            app.GrainEditField = uieditfield(app.GrainAnalyzerTab, 'numeric');
            app.GrainEditField.Limits = [1 Inf];
            app.GrainEditField.Value = 1;
            app.GrainLabel = uilabel(app.GrainAnalyzerTab);
            app.GrainLabel.Text = 'Grain: 0';
            app.WriteDataButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.WriteDataButton.ButtonPushedFcn = createCallbackFcn(app, @WriteDataButtonPushed, true);
            app.WriteDataButton.Text = 'Write Data';
            app.LoadDataButton = uibutton(app.GrainAnalyzerTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DopingMapTab = uitab(app.TabGroup);
            app.DopingMapTab.Title = 'Doping Map';
            app.MapAxes = uiaxes(app.DopingMapTab);
            app.MapAxes.XTick = [];
            app.MapAxes.YTick = [];
            app.SaveMapButton = uibutton(app.DopingMapTab, 'push');
            app.SaveMapButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMapButtonPushed, true);
            app.SaveMapButton.Text = 'Save Map';
            app.C2EditField = uieditfield(app.DopingMapTab, 'numeric');
            app.C2EditField.Limits = [0 Inf];
            app.C2EditField.ValueChangedFcn = createCallbackFcn(app, @C2EditFieldValueChanged, true);
            app.C2EditField.Value = 3;
            app.C1EditField = uieditfield(app.DopingMapTab, 'numeric');
            app.C1EditField.Limits = [0 Inf];
            app.C1EditField.ValueChangedFcn = createCallbackFcn(app, @C1EditFieldValueChanged, true);
            app.NEditFieldLabel = uilabel(app.DopingMapTab);
            app.NEditFieldLabel.Text = 'N:';
            app.NEditField = uieditfield(app.DopingMapTab, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 5;
            app.C2Label = uilabel(app.DopingMapTab);
            app.C2Label.Text = 'C2:';
            app.SiLabel = uilabel(app.DopingMapTab);
            app.SiLabel.Text = 'Si:';
            app.SiReadout = uieditfield(app.DopingMapTab, 'numeric');
            app.SiReadout.Editable = 'off';
            app.SelectPixelButton = uibutton(app.DopingMapTab, 'push');
            app.SelectPixelButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPixelButtonPushed, true);
            app.SelectPixelButton.Text = 'Select Pixel';
            app.CEditField_2Label_2 = uilabel(app.DopingMapTab);
            app.CEditField_2Label_2.Text = 'C1:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EDS_Grain_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end