classdef Raman_Plot_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        IndexLabel        matlab.ui.control.Label
        ButtonGroup       matlab.ui.container.ButtonGroup
        SiCButton         matlab.ui.control.ToggleButton
        B4CButton         matlab.ui.control.ToggleButton
        NoLinesButton     matlab.ui.control.ToggleButton
        SaveFigureButton  matlab.ui.control.Button
        SelectDataButton  matlab.ui.control.Button
        Slider            matlab.ui.control.Slider
        OneDownButton     matlab.ui.control.Button
        OneUpButton       matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        path
    
        trunc = [150, 1500];
        map_data
        x_size
        ij
    
        pt
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            delete(app.pt)

            index = floor(event.Value);
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);
            
            Index = num2str(index); Index = append('Index: ', Index);
            I = num2str(i); 
            J = num2str(j);
            IJ = append('X: ', I, '; Y: ', J);
  
            app.IndexLabel.Text = {Index, IJ};
        
            Wavenumber = app.map_data{i,j,1};
            Intensity = app.map_data{i,j,2};
            app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            cla(app.UIAxes)

            Index = 'Index: 1';
            IJ = 'X: 1; Y: 1';
            app.IndexLabel.Text = {Index, IJ};
            app.Slider.Value = 1;

            app.ButtonGroup.SelectedObject = app.NoLinesButton;
            
            %% Data Selection Prompt
            app.UIFigure.Visible = 'off';
            [file, app.path] = uigetfile({'*.txt'});
            app.UIFigure.Visible = 'on';

            data_name = fullfile(app.path, file);
            data = table2array(readtable(data_name));
            
            %% Logic for Scans of Different Dimensions
            % Determine data array size
            [~,x,~] = unique(data(:,1),'rows');
            [~,y,~] = unique(data(:,2),'rows');
            [~,s,~] = unique(data(:,3),'rows');
            
            [app.x_size, ~] = size(x);
            [y_size, ~] = size(y);
            [s_size, ~] = size(s);

            app.ij = app.x_size * y_size;

            app.Slider.Limits = [1 app.ij];
            
            if app.ij < 20
                app.Slider.MinorTicks = 1:1:app.ij;
                app.Slider.MajorTicks = 1:1:app.ij;
            elseif app.ij < 100
                app.Slider.MinorTicks = 1:1:app.ij;
                app.Slider.MajorTicks = 1:5:app.ij;
            elseif app.ij < 1000
                app.Slider.MinorTicks = 1:5:app.ij;
                app.Slider.MajorTicks = 1:50:app.ij;
            elseif app.ij < 5000
                app.Slider.MinorTicks = 1:10:app.ij;
                app.Slider.MajorTicks = 1:100:app.ij;
            else
                app.Slider.MinorTicks = 1:100:app.ij;
                app.Slider.MajorTicks = 1:1000:app.ij;
            end
            
            %% Convert raw data to plottable format
            app.map_data = cell(app.x_size,y_size,2);

            [~,trunc_start] = min(abs(data(1:s_size,3) - app.trunc(2)));
            [~,trunc_end] = min(abs(data(1:s_size,3) - app.trunc(1)));

            trunc_size = trunc_end - trunc_start + 1;

            start_offset = trunc_start - 1;
            end_offset = s_size - trunc_end;
            
            for j = 1:y_size
                for i = 1:app.x_size
                    a = (i-1)*s_size + (j-1)*app.x_size*s_size + 1;
                    a = a + start_offset;

                    b = i*s_size + (j-1)*app.x_size*s_size;
                    b = b - end_offset;
        
                    app.map_data(i,j,1) = mat2cell(data(b:-1:a,3),trunc_size,1);
                    app.map_data(i,j,2) = mat2cell(normalize(data(b:-1:a,4),1,'range'),trunc_size,1);
                end
            end
            
            %% Graph Labels
            title_text = '\textbf{Raman Viewer}';
            x_text = '\textbf{Raman Shift (cm$^{-1}$)}';
            y_text = '\textbf{Intensity}';
            
            %% Start Plot
            Wavenumber = app.map_data{1,1,1};
            Intensity = app.map_data{1,1,2};

            hold(app.UIAxes,'on')

            app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
            
            app.UIAxes.Title.String = title_text;
            app.UIAxes.Title.Interpreter = 'latex';
            app.UIAxes.Title.FontSize = 18;

            app.UIAxes.XLabel.String = x_text;
            app.UIAxes.XLabel.Interpreter = 'latex';
            app.UIAxes.XLabel.FontSize = 14;

            app.UIAxes.YLabel.String = y_text;
            app.UIAxes.YLabel.Interpreter = 'latex';
            app.UIAxes.YLabel.FontSize = 14;
            
            app.UIAxes.XLim = [Wavenumber(1) Wavenumber(end)];
            app.UIAxes.YLim = [0 1];    
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            exportgraphics(app.UIAxes, fullfile(app.path,'Raman_Plot.png'), 'Resolution', 300)
        end

        % Button pushed function: OneDownButton
        function OneDownButtonPushed(app, event)
            delete(app.pt)

            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index > 1
                index = index - 1;
            end
    
            app.Slider.Value = index;
    
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);
            
            Index = num2str(index); Index = append('Index: ', Index);
            I = num2str(i); 
            J = num2str(j);
            IJ = append('X: ', I, '; Y: ', J);
  
            app.IndexLabel.Text = {Index, IJ};
        
            Wavenumber = app.map_data{i,j,1};
            Intensity = app.map_data{i,j,2};
            app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Button pushed function: OneUpButton
        function OneUpButtonPushed(app, event)
            delete(app.pt)

            index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
            if index < app.ij
                index = index + 1;
            end
    
            app.Slider.Value = index;
    
            i = mod(index, app.x_size);
            if i == 0
                i = app.x_size;
            end
            j = ceil(index / app.x_size);
            
            Index = num2str(index); Index = append('Index: ', Index);
            I = num2str(i); 
            J = num2str(j);
            IJ = append('X: ', I, '; Y: ', J);
  
            app.IndexLabel.Text = {Index, IJ};
        
            Wavenumber = app.map_data{i,j,1};
            Intensity = app.map_data{i,j,2};
            app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text, 'No Lines')
                cla(app.UIAxes)

                index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
        
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                Wavenumber = app.map_data{i,j,1};
                Intensity = app.map_data{i,j,2};
                app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
            end

            if strcmp(selectedButton.Text, 'B4C')
                cla(app.UIAxes)

                index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
        
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                Wavenumber = app.map_data{i,j,1};
                Intensity = app.map_data{i,j,2};
                app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);
                
                search_loc = [460,500; 510,550; 700,760; 1040,1120];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end

            if strcmp(selectedButton.Text, 'SiC')
                cla(app.UIAxes)

                index = sscanf(app.IndexLabel.Text{1}, 'Index: %d');
        
                i = mod(index, app.x_size);
                if i == 0
                    i = app.x_size;
                end
                j = ceil(index / app.x_size);
            
                Wavenumber = app.map_data{i,j,1};
                Intensity = app.map_data{i,j,2};
                app.pt = plot(Wavenumber, Intensity, 'b', 'LineWidth', 2, 'Parent', app.UIAxes);

                search_loc = [770,810; 950,990];

                for l = 1:size(search_loc,1)
                    plot([search_loc(l,1) search_loc(l,1)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                    plot([search_loc(l,2) search_loc(l,2)], [0 1], 'r', 'LineWidth', 2, 'Parent', app.UIAxes)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OneUpButton = uibutton(app.UIFigure, 'push');
            app.OneUpButton.ButtonPushedFcn = createCallbackFcn(app, @OneUpButtonPushed, true);
            app.OneUpButton.Text = 'One Up';
            app.OneDownButton = uibutton(app.UIFigure, 'push');
            app.OneDownButton.ButtonPushedFcn = createCallbackFcn(app, @OneDownButtonPushed, true);
            app.OneDownButton.Text = 'One Down';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SelectDataButton = uibutton(app.UIFigure, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Text = 'Save Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NoLinesButton = uitogglebutton(app.ButtonGroup);
            app.NoLinesButton.Text = 'No Lines';
            app.NoLinesButton.Value = true;
            app.B4CButton = uitogglebutton(app.ButtonGroup);
            app.B4CButton.Text = 'B4C';
            app.SiCButton = uitogglebutton(app.ButtonGroup);
            app.SiCButton.Text = 'SiC';
            app.IndexLabel = uilabel(app.UIFigure);
            app.IndexLabel.Text = {'Index: 1'; 'X:1; Y:1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Raman_Plot_Viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end